classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DanewejciowePanel               matlab.ui.container.Panel
        FmEditField                     matlab.ui.control.EditField
        FmEditFieldLabel                matlab.ui.control.Label
        ChangeFuncTypeBox               matlab.ui.control.CheckBox
        FunkcjaceluDropDown             matlab.ui.control.DropDown
        FunkcjaceluDropDownLabel        matlab.ui.control.Label
        UstawieniaalgorytmuPanel        matlab.ui.container.Panel
        iterEditField                   matlab.ui.control.NumericEditField
        iterEditFieldLabel              matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        x_maxEditField                  matlab.ui.control.NumericEditField
        x_maxLabel                      matlab.ui.control.Label
        x_minEditField                  matlab.ui.control.NumericEditField
        x_minLabel                      matlab.ui.control.Label
        dimEditField                    matlab.ui.control.NumericEditField
        dimEditFieldLabel               matlab.ui.control.Label
        IloiteracjiLabel                matlab.ui.control.Label
        IloagentwLabel                  matlab.ui.control.Label
        OgraniczeniegrneLabel           matlab.ui.control.Label
        OgraniczeniedolneLabel          matlab.ui.control.Label
        LiczbazmiennychLabel            matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        maksymalizacjaButton            matlab.ui.control.RadioButton
        minimalizacjaButton             matlab.ui.control.RadioButton
        ObliczButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ALGORYTMSZAREGOWILKALabel       matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        OptymalnywynikEditFieldLabel_2  matlab.ui.control.Label
        OptymalnywynikEditField         matlab.ui.control.NumericEditField
        OptymalnywynikEditFieldLabel    matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        % function o = F7(x)
        %     F2 = 1;
        %     one = 0;
        %     two = 1;
        %     for i = 1:length(x)
        %         one = one + (x(i)^2/4000);
        %         two = two * (cos(x(i)/sqrt(i)));
        %     end
        %     o = F2 + one + two;
        % end

        function p_utr= p_utr(~, m)
            
            delta = 1 / T;
            beta = delta / M;
            ro = L / M;
            
            sum_nomi = 0;
            
            for r=1:m+N
                il = 1;
                for n=1:r
                    il = il * (m + n*beta);
                end
                sum_nomi = sum_nomi + (r * ro^r) / il;
            end

            nomi = (beta / ro) * (ro^m / factorial(m)) * sum_nomi;
            
            sum_denomi1 = 0;
            for k = 0:m
                sum_denomi1 = sum_denomi1 + (ro^k)/factorial(k);
            end

            sum_denomi2 = 0;
            for r=1:m+N
                il = 1;
                for n=1:r
                    il = il * (m + n*beta);
                end
                sum_denomi2 = sum_denomi2 + (ro^r)/il;
            end

            denomi = sum_denomi1 + sum_denomi2 * (ro^m)/(factorial(m));

            p_w = nomi / denomi;

            p0 = denomi^(-1);
            pm = ((ro^m)/(factorial(m))) * p0;

            p_utr = p_w + pm;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            
            clc;

            agents_num = app.NEditField.Value;
            iterations = app.iterEditField.Value;
            lb=app.x_minEditField.Value; 
            ub=app.x_maxEditField.Value;
            dim=app.dimEditField.Value;
            funcValue = '';
            if app.minimalizacjaButton.Value
                optType = 'MIN';
            else
                optType = 'MAX';
            end
            fun = 0;
            roundValues = false;


            funBoxValue = app.ChangeFuncTypeBox.Value;
            if funBoxValue
                
                funEditTextVal = app.FmEditField.Value;
                try
                    f = eval(strcat("@(m) ", funEditTextVal));
                    fun_map = @(arr) sum(arrayfun(f, arr));
                    fun = fun_map;
                catch
                    app.FmEditField.Value = "#ERR";
                    return;
                end
            else
                funcValue = app.FunkcjaceluDropDown.Value;

                switch funcValue
                    case "hyper_ellipse"
                        fun = @hyper_ellipse;
                    case "griewangk"
                        fun = @griewangk;
                    case "rosenbrock"
                        fun = @rosenbrock;
                    case "schwefel"
                        fun = @schwefel;
                    case "FIFO"
                        fun = @test_func;
                        roundValues = true;
                end
            end
            disp(roundValues)
            [best_score, best_pos, convergence_plot] = gray_wolf_opt(fun, dim, lb, ub, agents_num, iterations, optType, roundValues);

            % dziala, nareszczie
            %
            % w nienawisci do Matlaba zostalem wychowany
            % w mym domu Matlab wiec bedzie j###ny
            % bo ten jezyk jest k###a niedoj###ny
            %                                        ~ autor nieznany
            
            if lb < -10
                lb = -10;
            end
            if ub > 10
                ub = 10;
            end

            if roundValues == true
                step = 1;
            else
                step = 0.1;
            end
            x = lb:step:ub;
            y = x;
            l = length(x);
            z = zeros(length(x), length(y));
            for i = 1:l
                for j=1:l
                    z(i,j) = fun([x(i), y(j)]);
                end
            end
                
            plot(app.UIAxes,1,2)
            surfc(app.UIAxes,x,y,z, 'LineStyle', 'none');
            %sukces znowu to kurestwo zadzialalo xD
  
            plot(app.UIAxes2,1,2);
            semilogy(app.UIAxes2,convergence_plot,'Color','r')
            
            axis(app.UIAxes2,"tight")
            %box(on)
       
            legend(app.UIAxes2,'GWO')

            app.OptymalnywynikEditField.Value = best_score;
            app.UITable2.Data = best_pos;


        end

        % Value changed function: ChangeFuncTypeBox
        function ChangeFuncTypeBoxValueChanged(app, event)
            value = app.ChangeFuncTypeBox.Value;
            if value
                app.FunkcjaceluDropDown.Enable = false;
                
                app.FmEditField.Enable = true;

            else
                app.FunkcjaceluDropDown.Enable = true;
                a
                app.FmEditField.Enable = false;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Przestrzeń parametrów')
            xlabel(app.UIAxes, 'x_1')
            ylabel(app.UIAxes, 'x_2''')
            zlabel(app.UIAxes, 'F( x_1 , x_2 )')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Przestrzeń objektowa')
            xlabel(app.UIAxes2, 'Iteracja')
            ylabel(app.UIAxes2, 'Uzyskane wyniki')
            zlabel(app.UIAxes2, 'Z')
            app.OptymalnywynikEditFieldLabel = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel.Text = 'Optymalny wynik';
            app.OptymalnywynikEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptymalnywynikEditFieldLabel_2 = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel_2.Text = 'Optymalne pozycje';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Pozycja'};
            app.UITable2.RowName = {};
            app.ALGORYTMSZAREGOWILKALabel = uilabel(app.UIFigure);
            app.ALGORYTMSZAREGOWILKALabel.Text = 'ALGORYTM SZAREGO WILKA';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'profesor-rafal-wilczur.jpg');
            app.UstawieniaalgorytmuPanel = uipanel(app.UIFigure);
            app.UstawieniaalgorytmuPanel.Title = 'Ustawienia algorytmu';
            app.ObliczButton = uibutton(app.UstawieniaalgorytmuPanel, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.ButtonGroup = uibuttongroup(app.UstawieniaalgorytmuPanel);
            app.minimalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.minimalizacjaButton.Text = 'minimalizacja';
            app.minimalizacjaButton.Value = true;
            app.maksymalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.maksymalizacjaButton.Text = 'maksymalizacja';
            app.LiczbazmiennychLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.LiczbazmiennychLabel.Text = 'Liczba zmiennych';
            app.OgraniczeniedolneLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.OgraniczeniedolneLabel.Text = 'Ograniczenie dolne';
            app.OgraniczeniegrneLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.OgraniczeniegrneLabel.Text = 'Ograniczenie górne';
            app.IloagentwLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.IloagentwLabel.Text = 'Ilość agentów';
            app.IloiteracjiLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.IloiteracjiLabel.Text = 'Ilość iteracji';
            app.dimEditFieldLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.dimEditFieldLabel.Text = '(dim)';
            app.dimEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.x_minLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.x_minLabel.Text = '(x_min)';
            app.x_minEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.x_maxLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.x_maxLabel.Text = '(x_max)';
            app.x_maxEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.NEditFieldLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.NEditFieldLabel.Text = '(N)';
            app.NEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.iterEditFieldLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.iterEditFieldLabel.Text = '(iter)';
            app.iterEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.DanewejciowePanel = uipanel(app.UIFigure);
            app.DanewejciowePanel.Title = 'Dane wejściowe';
            app.FunkcjaceluDropDownLabel = uilabel(app.DanewejciowePanel);
            app.FunkcjaceluDropDownLabel.Text = 'Funkcja celu';
            app.FunkcjaceluDropDown = uidropdown(app.DanewejciowePanel);
            app.FunkcjaceluDropDown.Items = {'hyper_ellipse', 'griewangk', 'rosenbrock', 'schwefel', 'M/M/m/FIFO/m+N'};
            app.FunkcjaceluDropDown.ItemsData = {'hyper_ellipse', 'griewangk', 'rosenbrock', 'schwefel', 'FIFO'};
            app.FunkcjaceluDropDown.Value = 'hyper_ellipse';
            app.ChangeFuncTypeBox = uicheckbox(app.DanewejciowePanel);
            app.ChangeFuncTypeBox.ValueChangedFcn = createCallbackFcn(app, @ChangeFuncTypeBoxValueChanged, true);
            app.ChangeFuncTypeBox.Text = 'Kreator własnej funkcji';
            app.FmEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.FmEditFieldLabel.Text = 'F(m) =';
            app.FmEditField = uieditfield(app.DanewejciowePanel, 'text');
            app.FmEditField.Enable = 'off';
            app.FmEditField.Placeholder = 'np. sin(m) * cos(m) + 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end